classdef webapps_prewarm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Desabilita mensagens de alerta...
            disablingWarningMessages()

            % Habilita operação no modo paralelo...
            parpoolCheck()

            % Verifica se pasta temporária existe, criando-a, caso necessário.
            Folder   = fullfile(getenv('PROGRAMDATA'), 'ANATEL', 'MATLAB WebServer Cache');
            if ~isfolder(Folder)
                mkdir(Folder)
            end
            FullFile = fullfile(Folder, [datestr(now, "yyyymmdd") '_preWarm.txt']);

            % Grava em arquivo as condições de operação do PreWarm...
            t = datestr(now);            
            m = ver;
            m = {m.Name};            
            p = gcp('nocreate');
            if ~isempty(p)
                p = struct(p);
                p = rmfield(p, {'FevalQueue', 'FileStore', 'ValueStore', 'AutoListeners__'});
            end            
            writematrix(jsonencode(struct('timestamp', t, 'matlabProducts', {m}, 'parpool', p), 'PrettyPrint', true), FullFile, 'FileType', 'text', 'QuoteStrings', 'none', 'WriteMode', 'overwrite')

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = webapps_prewarm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end